classdef app_online < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Ylim_maxEditField       matlab.ui.control.NumericEditField
        Ylim_maxEditFieldLabel  matlab.ui.control.Label
        Ylim_minEditField       matlab.ui.control.NumericEditField
        Ylim_minEditFieldLabel  matlab.ui.control.Label
        MenuPrincipalButton     matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        AdquisicionTab          matlab.ui.container.Tab
        GuardarButton           matlab.ui.control.Button
        ARCHIVOtxtEditField     matlab.ui.control.EditField
        DetenerButton           matlab.ui.control.Button
        InicioButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        EstadisticaTab          matlab.ui.container.Tab
    end

    properties (Access = private)
        s % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %global Y_limMIM Y_limMAX
            app.Ylim_minEditField.Value = 0;
            app.Ylim_maxEditField.Value = 5.1;
            app.ARCHIVOtxtEditField.Value = 'data0.txt';
            app.TextArea.Value = '[CAPTURA DE DATOS] Esperando...';
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            app.TextArea.Value = 'Cargando...';
            global parar datos voltaje muestras puerto baud_rNUM delay Y_limMIM Y_limMAX
            parar = 0;
            pause(10);
            app.TextArea.Value = 'Capturando datos...';
            app.s = serialport(puerto,baud_rNUM);
            voltaje = 0;
            contador = 1;
            while contador <= muestras
                if parar == 1
                    break
                else
                    Y_limMIM = app.Ylim_minEditField.Value;
                    Y_limMAX = app.Ylim_maxEditField.Value;
                    valorADC = str2double(readline(app.s));
                    %const(contador) = 5;
                    voltaje(contador) = valorADC(1)*5/1023;
                    tiempo(contador) = contador*delay/1000;
                    title(app.UIAxes, ['Datos del puerto ' puerto])
                    app.UIAxes.XLim = [0 (contador*delay/1000)+3];
                    app.UIAxes.YLim = [Y_limMIM 5.1];
                    plot(app.UIAxes,tiempo,voltaje)
                    datos = [voltaje;tiempo];
                    %app.UI
                    contador = contador + 1;
                end
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.TextArea.Value = 'Captura finalizada por el usuario.';
            global parar
            parar = 1;
        end

        % Button pushed function: MenuPrincipalButton
        function MenuPrincipalButtonPushed(app, event)
           global online_c
           online_c = 0;
           app.delete;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            global datos
            nombre_data = app.ARCHIVOtxtEditField.Value;
            writematrix(datos',nombre_data);
            app.TextArea.Value = ['Datos guardados como ' nombre_data];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdquisicionTab = uitab(app.TabGroup);
            app.AdquisicionTab.Title = 'Adquisicion';
            app.UIAxes = uiaxes(app.AdquisicionTab);
            title(app.UIAxes, 'Datos')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Voltaje [V]')
            zlabel(app.UIAxes, 'Z')
            app.InicioButton = uibutton(app.AdquisicionTab, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.DetenerButton = uibutton(app.AdquisicionTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.ARCHIVOtxtEditField = uieditfield(app.AdquisicionTab, 'text');
            app.ARCHIVOtxtEditField.Value = 'datos0';
            app.GuardarButton = uibutton(app.AdquisicionTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.EstadisticaTab = uitab(app.TabGroup);
            app.EstadisticaTab.Title = 'Estadistica';
            app.TextArea = uitextarea(app.UIFigure);
            app.MenuPrincipalButton = uibutton(app.UIFigure, 'push');
            app.MenuPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @MenuPrincipalButtonPushed, true);
            app.MenuPrincipalButton.Text = 'Menu Principal';
            app.Ylim_minEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_minEditFieldLabel.Text = 'Ylim_min';
            app.Ylim_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ylim_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_maxEditFieldLabel.Text = 'Ylim_max';
            app.Ylim_maxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_online

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end