classdef app_principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SalirButton                  matlab.ui.control.Button
        BaudRateDropDown             matlab.ui.control.DropDown
        BaudRateDropDownLabel        matlab.ui.control.Label
        TiemposEditField             matlab.ui.control.NumericEditField
        TiemposEditFieldLabel        matlab.ui.control.Label
        MuestrasEditField            matlab.ui.control.NumericEditField
        PuertoEditField              matlab.ui.control.EditField
        MuestrasEditFieldLabel       matlab.ui.control.Label
        PuertoEditFieldLabel         matlab.ui.control.Label
        DelaymsEditField             matlab.ui.control.NumericEditField
        DelaymsEditFieldLabel        matlab.ui.control.Label
        IniciarButton_Offline        matlab.ui.control.Button
        Iniciar_Online               matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        BuscarActualizacionesButton  matlab.ui.control.Button
        OnlineGUILabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global delay muestras baud_r baud_rNUM online_c puerto
            delay = 100;
            muestras = 150;
            app.DelaymsEditField.Value = delay;
            app.MuestrasEditField.Value = muestras;
            app.TiemposEditField.Value = delay*muestras/1000;
            app.PuertoEditField.Value = '/dev/ttyACM0';
            puerto = app.PuertoEditField.Value;
            baud_r = app.BaudRateDropDown.Value;
            baud_rNUM = str2num(baud_r);
            %% contadores
            online_c = 0;
        end

        % Value changed function: DelaymsEditField
        function DelaymsEditFieldValueChanged(app, event)
            global delay muestras
            delay = app.DelaymsEditField.Value;
            app.TiemposEditField.Value = delay*muestras;
        end

        % Value changed function: MuestrasEditField
        function MuestrasEditFieldValueChanged(app, event)
            global delay muestras
            muestras = app.MuestrasEditField.Value;
            app.TiemposEditField.Value = delay*muestras/1000;
        end

        % Value changed function: PuertoEditField
        function PuertoEditFieldValueChanged(app, event)
            global puerto
            puerto = app.PuertoEditField.Value;
        end

        % Value changed function: BaudRateDropDown
        function BaudRateDropDownValueChanged(app, event)
            global baud_r baud_rNUM
            baud_r = app.BaudRateDropDown.Value;
            baud_rNUM = str2num(baud_r);
        end

        % Button pushed function: Iniciar_Online
        function Iniciar_OnlineButtonPushed(app, event)
         global online_c
            if online_c == 0
                online_c = 1;
                app_online;
            end
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            welcome;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OnlineGUILabel = uilabel(app.UIFigure);
            app.OnlineGUILabel.Text = 'Online GUI';
            app.BuscarActualizacionesButton = uibutton(app.UIFigure, 'push');
            app.BuscarActualizacionesButton.Text = {'Buscar '; 'Actualizaciones'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'updatelogo.png';
            app.Iniciar_Online = uibutton(app.UIFigure, 'push');
            app.Iniciar_Online.ButtonPushedFcn = createCallbackFcn(app, @Iniciar_OnlineButtonPushed, true);
            app.Iniciar_Online.Text = 'Iniciar';
            app.IniciarButton_Offline = uibutton(app.UIFigure, 'push');
            app.IniciarButton_Offline.Text = 'Iniciar';
            app.DelaymsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaymsEditFieldLabel.Text = 'Delay [ms]';
            app.DelaymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelaymsEditField.ValueChangedFcn = createCallbackFcn(app, @DelaymsEditFieldValueChanged, true);
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.MuestrasEditFieldLabel = uilabel(app.UIFigure);
            app.MuestrasEditFieldLabel.Text = 'Muestras';
            app.PuertoEditField = uieditfield(app.UIFigure, 'text');
            app.PuertoEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoEditFieldValueChanged, true);
            app.MuestrasEditField = uieditfield(app.UIFigure, 'numeric');
            app.MuestrasEditField.ValueChangedFcn = createCallbackFcn(app, @MuestrasEditFieldValueChanged, true);
            app.TiemposEditFieldLabel = uilabel(app.UIFigure);
            app.TiemposEditFieldLabel.Text = 'Tiempo[s]';
            app.TiemposEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {'9600', '19200', '14400', '4800'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRateDropDownValueChanged, true);
            app.BaudRateDropDown.Value = '9600';
            app.SalirButton = uibutton(app.UIFigure, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end