classdef app_offline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ListBox       matlab.ui.control.ListBox
        ListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.s = serialport("/dev/ttyACM1",9600);
        end

        % Callback function
        function InicioButtonPushed(app, event)
            global parar datos voltaje const
            app.s = serialport("/dev/ttyACM1",9600);
            parar = 0;
            muestras = 50;
            voltaje = 0;
            contador = 1;
            while contador <= muestras
                if parar == 1
                    break
                else
                    valorADC = str2double(readline(app.s));
                    const(contador) = 5;
                    voltaje(contador) = valorADC(1)*5/1023;
                    plot(app.UIAxes,voltaje)
                    app.UIAxes.XLim = [0 contador+50];
                    app.UIAxes.YLim = [0 5.1];
                    tiempo(contador) = contador;
                    datos = [voltaje;tiempo];
                    %app.UI
                    contador = contador + 1;
                end
            end

        end

        % Callback function
        function DetenerButtonPushed(app, event)
            global parar
            parar = 1;
        end

        % Callback function
        function GuardarcomotxtButtonPushed(app, event)
             global datos
             writematrix(datos','datos.txt')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pasa Bajas', 'Pasa Altas', 'Rechaza Banda', 'Item 4'};
            app.ListBox.Value = 'Pasa Bajas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_offline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end