classdef app_offline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AbirtxtButton    matlab.ui.control.Button
        DisearButton     matlab.ui.control.Button
        RefrescarButton  matlab.ui.control.Button
        ListBox2         matlab.ui.control.ListBox
        EditField        matlab.ui.control.EditField
        ListBox          matlab.ui.control.ListBox
        TextArea         matlab.ui.control.TextArea
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             global datos datos_generados num_datos delay
             app.ListBox2.Enable = 1;
             dat_prev = exist('datos_generados');
             if dat_prev ~= 0
                 if datos_generados == 1
                      tx_datos = "Se han detectado datos recientemente adquiridos."+newline+...
                          "[info] " + "Numero de datos: " + convertCharsToStrings(num_datos)+...
                          ". Frecuencia de muestreo: " + string(1000/delay) +" Hz.";
                      datos2 = transpose(datos);
                 else
                     tx_datos = "No se han detectado datos recientemente adquiridos.";
                 end
             end
             if dat_prev == 0
                 tx_datos = "No se han detectado datos recientemente adquiridos.";
             end
             app.TextArea.Value = "[Datos] " + tx_datos+...
                         newline + "[Tarea] " + convertCharsToStrings(app.ListBox.Value);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if contains(app.ListBox.Value, 'Diseño del filtro')
                app.DisearButton.Enable = 1;
            end
            if contains(app.ListBox.Value,'Analisis frecuencial')
                app.DisearButton.Enable = 0;
            end
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
            %app.VariablesxaxisListBox2.Items = ['a','b','c'];
            arch = dir('*.mat');
            nombres=[{arch().name}];
            app.ListBox2.Items = nombres;
        end

        % Button pushed function: AbirtxtButton
        function AbirtxtButtonPushed(app, event)
            file = uigetfile('*.txt');
            app.EditField.Value = file;
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            value = app.ListBox2.Value;
             app.TextArea.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Diseño del filtro', 'Analisis frecuencial'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Diseño del filtro';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ListBox2 = uilistbox(app.UIFigure);
            app.ListBox2.Items = {'No hay filtros cargados'};
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.ListBox2.Value = 'No hay filtros cargados';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.DisearButton = uibutton(app.UIFigure, 'push');
            app.DisearButton.Text = 'Diseñar';
            app.AbirtxtButton = uibutton(app.UIFigure, 'push');
            app.AbirtxtButton.ButtonPushedFcn = createCallbackFcn(app, @AbirtxtButtonPushed, true);
            app.AbirtxtButton.Text = 'Abir (.txt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_offline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end